classdef deneme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PNEUMONIADETECTIONLabel   matlab.ui.control.Label
        ResultofmyModelLabel      matlab.ui.control.Label
        ResultofMobileNetv2Label  matlab.ui.control.Label
        MyModelButton             matlab.ui.control.Button
        MobileNetv2Button         matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        BrowseButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %load("EgitilmişAgMobileNet.mat")
            [filename,path]=uigetfile({'*.jpeg;'});
            fullpath=fullfile(path,filename);
          
           img=imread(fullpath);
           img=imresize(img,[224 224]);
           app.Image.ImageSource=fullpath
           app.ResultofMobileNetv2Label.Text="Result of MobileNetv2"
            app.ResultofmyModelLabel.Text="Result of MyModel"
         %  result=classify(trainedNetwork_1,img)
          % app.ResultofMobileNetv2Label.Text=result
          
        end

        % Button pushed function: MobileNetv2Button
        function MobileNetv2ButtonPushed(app, event)
            text1=app.Image.ImageSource
          %  app.ResultofMobileNetv2Label.Text=text1
            load("EgitilmişAgMobileNet.mat")
            img=imread(text1);
            img=imresize(img,[224 224]);
            result=classify(trainedNetwork_1,img)
            app.ResultofMobileNetv2Label.Text=result
        end

        % Button pushed function: MyModelButton
        function MyModelButtonPushed(app, event)
            load("Mymodel_trained.mat")
            text1=app.Image.ImageSource
            img=imread(text1);
            img=imresize(img,[224 224]);
            result=classify(myModelTrained,img)
            app.ResultofmyModelLabel.Text=result
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.UIFigure);
            app.MobileNetv2Button = uibutton(app.UIFigure, 'push');
            app.MobileNetv2Button.ButtonPushedFcn = createCallbackFcn(app, @MobileNetv2ButtonPushed, true);
            app.MobileNetv2Button.Text = 'MobileNetv2';
            app.MyModelButton = uibutton(app.UIFigure, 'push');
            app.MyModelButton.ButtonPushedFcn = createCallbackFcn(app, @MyModelButtonPushed, true);
            app.MyModelButton.Text = 'MyModel';
            app.ResultofMobileNetv2Label = uilabel(app.UIFigure);
            app.ResultofMobileNetv2Label.Text = 'Result of MobileNetv2';
            app.ResultofmyModelLabel = uilabel(app.UIFigure);
            app.ResultofmyModelLabel.Text = 'Result of myModel';
            app.PNEUMONIADETECTIONLabel = uilabel(app.UIFigure);
            app.PNEUMONIADETECTIONLabel.Text = 'PNEUMONIA DETECTION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deneme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end